classdef FinalSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        uManualEditField               matlab.ui.control.NumericEditField
        uManualEditFieldLabel          matlab.ui.control.Label
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        AutoButton_2                   matlab.ui.control.RadioButton
        ManualButton                   matlab.ui.control.RadioButton
        AutonomousSteeringSimulationLabel  matlab.ui.control.Label
        t                              matlab.ui.control.NumericEditField
        TimetoRunEditFieldLabel        matlab.ui.control.Label
        v                              matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel       matlab.ui.control.Label
        PathLabel                      matlab.ui.control.Label
        AutoSteeringCoefficientsLabel  matlab.ui.control.Label
        one                            matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        x                              matlab.ui.control.NumericEditField
        xLabel                         matlab.ui.control.Label
        x2                             matlab.ui.control.NumericEditField
        x2Label                        matlab.ui.control.Label
        x3                             matlab.ui.control.NumericEditField
        x3Label                        matlab.ui.control.Label
        fxLabel                        matlab.ui.control.Label
        E                              matlab.ui.control.NumericEditField
        ErrorEditFieldLabel            matlab.ui.control.Label
        C                              matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        B                              matlab.ui.control.NumericEditField
        BEditFieldLabel                matlab.ui.control.Label
        A                              matlab.ui.control.NumericEditField
        AEditFieldLabel                matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            cla(app.UIAxes)

            if app.ManualButton.Value
                u = app.uManualEditField.Value;
            else
                u = NaN;
            end
            
            [x,y,E] = Simulation(u, app.v.Value, app.A.Value, app.B.Value, app.C.Value, app.x3.Value, app.x2.Value, app.x.Value, app.one.Value, app.t.Value);

            syms f(fx)

            if ~app.ManualButton.Value
                f(fx) = app.x3.Value * fx^3 + app.x2.Value * fx^2 + app.x.Value * fx + app.one.Value;
                fplot(app.UIAxes, f(fx),[0,50], 'r*')
                hold(app.UIAxes, "on")
                app.E.Value = double(real(E));
            else
                app.E.Value = 0;
            end

            plot(app.UIAxes, x, y, 'g')
            xlim(app.UIAxes, [min(real(x)), max(real(x))+1])
            ylim(app.UIAxes, [min(real(y)), max(real(y))+1])
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Path')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z')
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.A = uieditfield(app.UIFigure, 'numeric');
            app.A.Limits = [0 Inf];
            app.A.Value = 0.01;
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.B = uieditfield(app.UIFigure, 'numeric');
            app.B.Limits = [0 Inf];
            app.B.Value = 0.16;
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.C = uieditfield(app.UIFigure, 'numeric');
            app.C.Limits = [0 Inf];
            app.C.Value = 0.05;
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.E = uieditfield(app.UIFigure, 'numeric');
            app.E.Editable = 'off';
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x) = ';
            app.x3Label = uilabel(app.UIFigure);
            app.x3Label.Text = 'x^3 * ';
            app.x3 = uieditfield(app.UIFigure, 'numeric');
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = '+x^2 * ';
            app.x2 = uieditfield(app.UIFigure, 'numeric');
            app.x2.Value = 1;
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = '+x * ';
            app.x = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.one = uieditfield(app.UIFigure, 'numeric');
            app.one.Value = 1;
            app.AutoSteeringCoefficientsLabel = uilabel(app.UIFigure);
            app.AutoSteeringCoefficientsLabel.Text = 'Auto Steering Coefficients';
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path';
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.v = uieditfield(app.UIFigure, 'numeric');
            app.v.Limits = [0 70];
            app.v.Value = 15;
            app.TimetoRunEditFieldLabel = uilabel(app.UIFigure);
            app.TimetoRunEditFieldLabel.Text = 'Time to Run?';
            app.t = uieditfield(app.UIFigure, 'numeric');
            app.t.Limits = [0 20];
            app.t.Value = 5;
            app.AutonomousSteeringSimulationLabel = uilabel(app.UIFigure);
            app.AutonomousSteeringSimulationLabel.Text = 'Autonomous Steering Simulation';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.Title = 'Mode';
            app.ManualButton = uiradiobutton(app.ModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutoButton_2 = uiradiobutton(app.ModeButtonGroup);
            app.AutoButton_2.Text = 'Auto';
            app.uManualEditFieldLabel = uilabel(app.UIFigure);
            app.uManualEditFieldLabel.Text = 'u (Manual)';
            app.uManualEditField = uieditfield(app.UIFigure, 'numeric');
            app.uManualEditField.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end